classdef Signal_box_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        NoiseKnobLabel             matlab.ui.control.Label
        Noise                      matlab.ui.control.DiscreteKnob
        Label                      matlab.ui.control.Label
        PeriodKnobLabel            matlab.ui.control.Label
        Period                     matlab.ui.control.Knob
        DominSwitchLabel           matlab.ui.control.Label
        Domin                      matlab.ui.control.Switch
        LampLabel                  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Panel                      matlab.ui.container.Panel
        add                        matlab.ui.control.Button
        minus                      matlab.ui.control.Button
        AmpEditFieldLabel          matlab.ui.control.Label
        Amp                        matlab.ui.control.NumericEditField
        SignalSelectDropDownLabel  matlab.ui.control.Label
        SignalSelect               matlab.ui.control.DropDown
        Mul                        matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        AnalyzeLabel               matlab.ui.control.Label
        Analyze                    matlab.ui.control.DiscreteKnob
        pass_upSliderLabel         matlab.ui.control.Label
        pass_up                    matlab.ui.control.Slider
        stop_upSliderLabel         matlab.ui.control.Label
        stop_up                    matlab.ui.control.Slider
        stop_downSliderLabel       matlab.ui.control.Label
        stop_down                  matlab.ui.control.Slider
        pass_downSliderLabel       matlab.ui.control.Label
        pass_down                  matlab.ui.control.Slider
        FilterLabel                matlab.ui.control.Label
        Filter                     matlab.ui.control.DiscreteKnob
    end


    properties (Access = private)
        autoupdate % Description
    end

    methods (Access = private)
        function updatePlot(app)
            results = generateSignal(app);
            plot(app.UIAxes, results(1,:), results(2,:));
        end
        
        function results = generateSignal(app)
            signal = app.SignalSelect.Value;
            noise = str2double(app.Noise.Value);
            amp = app.Amp.Value;
            filter = app.Filter.Value;
            domin = app.Domin;
            f = app.Period.Value;
            fs = 1000;
            t = 0:1/fs:1;
            switch signal
                case 'None'
                    x = zeros(length(t));
                case 'Square'
                    x = square(2*pi*f*t,50);
                case 'Triangular'
                    x = sawtooth(2*pi*f*t,0.5);
                case 'Sine'
                    x = sin(2*pi*f*t);
                otherwise
                    x = zeros(length(t));
            end
            x = x * amp;
            x = awgn(x,noise,'measured');
            
            if(strcmp(app.Analyze,'Power'))
                temp = powerAnalyze(app,t,x);
                t = temp(:,1);x = temp(:,2);
            elseif(strcmp(app.Analyze, 'Spectrum'))
                temp = spectrumAnalyze(app,t,x);
                t = temp(:,1);x = temp(:,2);
            end
            
                      
            if(strcmp(app.Domin,'Freq'))
                results = time2str(t,x);
            else
                results = [t;x];
            end
        end
        
%         function Filter_generate(app)
%             switch app.Filter.Value
%                 case 'LPF'
%                     
%             end
%         end
        
        function results = time2freq(~,t, x)
            NFET = 2^nextpow2(length(t));
            X = fft(x, NFET)/length(t);
            results = [1:NFET;X];
        end
        
        function results = sprectrumAnalyze(~,t,x)
            n=0:(N-1);
            f=n*Fs/N;
            window=boxcar(length(x)); %矩形窗 
            [Pxx,f]=periodogram(x,window,length(x),f); %直接法
            x = 10*log10(Pxx);
            t = f;
%                 app.UIAxes.xlim([0,0.5*fs]);
%                 app.UIAxes.title('普通信号功率谱分析');
%                 app.UIAxes.xlabel('模拟频率(Hz)');
%                 app.UIAxes.ylabel('功率谱密度(dB)');
            results = [t, x];
        end
        
        function results = powerAnalyze(~,t,x)
           N=length(x);
           n=0:(N-1);
           f=n*length(t)/N;
           y=fft(x);
           W=abs(y);
           t = f(1:N/2);
           x = abs(W(1:N/2))*2/N;
%                app.UIAxes.xlim([0,0.5*Fs]);
%                app.UIAxes.xlabel('模拟频率(Hz)');
%                app.UIAxes.ylabel('振幅');
%                app.UIAxes.title('音频信号频谱分析');
            results = [t, x];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pass_up.Visible = 'off';
            app.stop_up.Visible = 'off';
            app.stop_down.Visible = 'off';
            app.pass_down.Visible = 'off';
        end

        % Value changed function: SignalSelect
        function SignalSelectValueChanged(app, event)
            value = app.SignalSelect.Value;
            updatePlot(app);
        end

        % Value changing function: Period
        function PeriodValueChanging(app, event)
            changingValue = event.Value;
            updatePlot(app);
        end

        % Value changed function: Noise
        function NoiseValueChanged(app, event)
            value = app.Noise.Value;
            updatePlot(app);
        end

        % Callback function
        function SignalAnalyzeSelectionChanged(app, event)
            selectedButton = app.SignalAnalyze.SelectedObject;
            updatePlot(app);
        end

        % Value changed function: Analyze
        function AnalyzeValueChanged(app, event)
            value = app.Analyze.Value;
            updatePlot(app);
        end

        % Value changed function: Filter
        function FilterValueChanged(app, event)
            value = app.Filter.Value;
            switch value
                case 'None'
                    app.pass_up.Visible = 'off';
                    app.stop_up.Visible = 'off';
                    app.stop_down.Visible = 'off';
                    app.pass_down.Visible = 'off';
                case 'LPF'
                    app.pass_up.Visible = 'on';
                    app.stop_up.Visible = 'on';
                    app.stop_down.Visible = 'off';
                    app.pass_down.Visible = 'off';
                case 'BPF'
                    app.pass_up.Visible = 'on';
                    app.stop_up.Visible = 'on';
                    app.stop_down.Visible = 'on';
                    app.pass_down.Visible = 'on';
                case 'HPF'
                    app.pass_up.Visible = 'off';
                    app.stop_up.Visible = 'off';
                    pp.stop_down.Visible = 'on';
                    app.pass_down.Visible = 'on';
            end
        end

        % Value changed function: Domin
        function DominValueChanged(app, event)
            value = app.Domin.Value;
            updatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal Display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NoiseKnobLabel = uilabel(app.UIFigure);
            app.NoiseKnobLabel.Text = 'Noise';
            app.Noise = uiknob(app.UIFigure, 'discrete');
            app.Noise.Items = {'100', '50', '20', '10', '1'};
            app.Noise.ValueChangedFcn = createCallbackFcn(app, @NoiseValueChanged, true);
            app.Noise.Value = '100';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数字信号处理大作业';
            app.PeriodKnobLabel = uilabel(app.UIFigure);
            app.PeriodKnobLabel.Text = 'Period';
            app.Period = uiknob(app.UIFigure, 'continuous');
            app.Period.Limits = [1 10];
            app.Period.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Period.ValueChangingFcn = createCallbackFcn(app, @PeriodValueChanging, true);
            app.Period.Value = 1;
            app.DominSwitchLabel = uilabel(app.UIFigure);
            app.DominSwitchLabel.Text = 'Domin';
            app.Domin = uiswitch(app.UIFigure, 'slider');
            app.Domin.Items = {'Time', 'Freq'};
            app.Domin.ValueChangedFcn = createCallbackFcn(app, @DominValueChanged, true);
            app.Domin.Value = 'Time';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.add = uibutton(app.Panel, 'push');
            app.add.Text = '+';
            app.minus = uibutton(app.Panel, 'push');
            app.minus.Text = '-';
            app.AmpEditFieldLabel = uilabel(app.Panel);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.Amp = uieditfield(app.Panel, 'numeric');
            app.Amp.Value = 1;
            app.SignalSelectDropDownLabel = uilabel(app.Panel);
            app.SignalSelectDropDownLabel.Text = 'Signal Select';
            app.SignalSelect = uidropdown(app.Panel);
            app.SignalSelect.Items = {'None', 'Square', 'Triangular', 'Sine'};
            app.SignalSelect.ValueChangedFcn = createCallbackFcn(app, @SignalSelectValueChanged, true);
            app.SignalSelect.Value = 'None';
            app.Mul = uibutton(app.Panel, 'push');
            app.Mul.Text = 'Mul';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Signal List'};
            app.UITable.RowName = {};
            app.AnalyzeLabel = uilabel(app.UIFigure);
            app.AnalyzeLabel.Text = 'Analyze';
            app.Analyze = uiknob(app.UIFigure, 'discrete');
            app.Analyze.Items = {'None', 'Spectrum', 'Power'};
            app.Analyze.ValueChangedFcn = createCallbackFcn(app, @AnalyzeValueChanged, true);
            app.Analyze.Value = 'None';
            app.pass_upSliderLabel = uilabel(app.UIFigure);
            app.pass_upSliderLabel.Text = 'pass_up';
            app.pass_up = uislider(app.UIFigure);
            app.stop_upSliderLabel = uilabel(app.UIFigure);
            app.stop_upSliderLabel.Text = 'stop_up';
            app.stop_up = uislider(app.UIFigure);
            app.stop_downSliderLabel = uilabel(app.UIFigure);
            app.stop_downSliderLabel.Text = 'stop_down';
            app.stop_down = uislider(app.UIFigure);
            app.pass_downSliderLabel = uilabel(app.UIFigure);
            app.pass_downSliderLabel.Text = 'pass_down';
            app.pass_down = uislider(app.UIFigure);
            app.FilterLabel = uilabel(app.UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.Filter = uiknob(app.UIFigure, 'discrete');
            app.Filter.Items = {'None', 'LPF', 'BPF', 'HPF'};
            app.Filter.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Filter.Value = 'None';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Signal_box_v3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end